classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        IndividualLicense   matlab.ui.control.TextArea
        IMAGESLabel         matlab.ui.control.Label
        AnalyzeImageButton  matlab.ui.control.Button
        ImageAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property
        CurrentImage            % Stores the currently loaded image data
        CurrentImagePath        % Stores the path to the current image
    end
    
    methods (Access = private)

        % --- Analyze Image ---
        function AnalyzeImageButtonPushed(app, ~)
            filterspec = {'*.jpg;*.png;*.bmp;*.tif','All Image Files (*.jpg, *.png, *.bmp, *.tif)'};
            [file, path] = uigetfile(filterspec, 'Select an Image');
            if isequal(file,0) || isequal(path,0)
                disp('User canceled image selection.');
                return;
            end
            app.CurrentImagePath = fullfile(path, file);
            try
                % Read the selected image
                app.CurrentImage = imread(app.CurrentImagePath);
            catch ME
                uialert(app.UIFigure, ME.message, 'Image Reading Error');
                app.CurrentImage = [];
                cla(app.ImageAxes); 
                app.IndividualLicense.Value = '';
                return;
            end
            % --- Display the image on the axes ---
            imshow(app.CurrentImage, 'Parent', app.ImageAxes);
            title(app.ImageAxes, file, 'Interpreter', 'none'); 
            drawnow; 

            % --- Detect License Plate ---
            disp('Starting geometric detection...');
            try
                bbox = detectLicensePlate(app.CurrentImage);
            catch detectError
                 uialert(app.UIFigure, ['Error during geometric detection: ' detectError.message], 'Detection Error');
                 disp(detectError.getReport); 
                 bbox = [];
            end
            disp('Geometric detection finished.');

            % --- Display Results ---
            if ~isempty(bbox)
                disp('Plate BBox found.');
                % --- Draw Rectangle ---
                hold(app.ImageAxes, 'on'); 
                rectangle(app.ImageAxes, 'Position', bbox, 'EdgeColor', 'c', 'LineWidth', 2);
                hold(app.ImageAxes, 'off'); 

                % --- Display Status ---
                app.IndividualLicense.Value = 'Plate Detected';
                disp('Plate Bounding Box Drawn.');

            else
                disp('License Plate Not Detected.');
                app.IndividualLicense.Value = 'License Plate Not Detected.';
                imshow(app.CurrentImage, 'Parent', app.ImageAxes);
                title(app.ImageAxes, file, 'Interpreter', 'none');
            end
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'on';
            app.ImageAxes.XTick = [];     
            app.ImageAxes.YTick = [];     
            axis(app.ImageAxes, 'image'); 
            title(app.ImageAxes,'');     
            cla(app.ImageAxes);
            app.IndividualLicense.Value = '';
        end

        % Button pushed function: AnalyzeImageButton
        function AnalyzeImageButtonPushed2(app, event)
            AnalyzeImageButtonPushed(app, event)
        end

        % Callback function: not associated with a component
        function AnalyzeDirectoryButtonPushed2(app, event)
            AnalyzeDirectoryButtonPushed(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.AnalyzeImageButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeImageButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeImageButtonPushed2, true);
            app.AnalyzeImageButton.Text = 'AnalyzeImage';
            app.IMAGESLabel = uilabel(app.UIFigure);
            app.IMAGESLabel.Text = 'IMAGES';
            app.IndividualLicense = uitextarea(app.UIFigure);
            app.IndividualLicense.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end